classdef ADRESS_RIXS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RIXSplotUIFigure          matlab.ui.Figure
        OpenButton                matlab.ui.control.Button
        PATHEditFieldLabel        matlab.ui.control.Label
        PATHEditField             matlab.ui.control.EditField
        ListBox                   matlab.ui.control.ListBox
        DispersionEditFieldLabel  matlab.ui.control.Label
        DispersionEditField       matlab.ui.control.EditField
        PlotButton                matlab.ui.control.Button
        ShiftEditField            matlab.ui.control.EditField
        ShiftButton               matlab.ui.control.Button
        SaveButton                matlab.ui.control.Button
        CompareCheckBox           matlab.ui.control.CheckBox
        INFOTextAreaLabel         matlab.ui.control.Label
        INFOTextArea              matlab.ui.control.TextArea
        PositionEditField         matlab.ui.control.EditField
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            global filepath fileinfo;
            filepath = uigetdir('C:\');
            app.PATHEditField.Value = filepath;
            fileinfo = dir([filepath,'/*.h5']);
            app.ListBox.Items = {fileinfo.name};
            cd(filepath);
        end

        % Value changed function: PATHEditField
        function PATHEditFieldValueChanged(app, event)
            global filepath
            value = app.PATHEditField.Value;
            filepath = value;
            fileinfo = dir([filepath,'/*.h5']);
            app.ListBox.Items = {fileinfo.name};
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global xdata ydata energydispersion;
            filelist = app.ListBox.Value;
            energydispersion = str2double(app.DispersionEditField.Value);
            [xdata,ydata] = RIXSplot(filelist);
            plot(app.UIAxes,xdata,ydata);
%             datacursormode(app.RIXSplotUIFigure, 'on' )
        end

        % Button pushed function: ShiftButton
        function ShiftButtonPushed(app, event)
            global xdata ydata;
            shiftvalue = app.ShiftEditField.Value;
            xdata = xdata + str2double(shiftvalue);
            plot(app.UIAxes,xdata,ydata);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            infovalue = app.ListBox.Value;
            app.INFOTextArea.Value = RIXSinfo(infovalue);
%             value = app.CompareCheckBox.Value;
%             if value == 0
%                 PlotButtonPushed(app, event);
%             end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            global xdata ydata;
            data = [xdata,ydata];
            [fname,pth] = uiputfile('.txt');
            dlmwrite([pth,fname],data,'delimiter','\t');
        end

        % Value changed function: CompareCheckBox
        function CompareCheckBoxValueChanged(app, event)
            value = app.CompareCheckBox.Value;
            if value == 1
                app.UIAxes.NextPlot = 'add';
            else
                app.UIAxes.NextPlot = 'replacechildren';
            end
        end

        % Window button motion function: RIXSplotUIFigure
        function RIXSplotUIFigureWindowButtonMotion(app, event)
            currPt = app.UIAxes.CurrentPoint;
            app.PositionEditField.Value = sprintf('( %.3f , %.3f )',currPt(1,1),currPt(1,2));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RIXSplotUIFigure = uifigure('Visible', 'off');
            app.RIXSplotUIFigure.Name = 'RIXSplot';
            app.RIXSplotUIFigure.Icon = 'icon.ico';
            app.RIXSplotUIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @RIXSplotUIFigureWindowButtonMotion, true);
            app.OpenButton = uibutton(app.RIXSplotUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.PATHEditFieldLabel = uilabel(app.RIXSplotUIFigure);
            app.PATHEditFieldLabel.Text = 'PATH';
            app.PATHEditField = uieditfield(app.RIXSplotUIFigure, 'text');
            app.PATHEditField.ValueChangedFcn = createCallbackFcn(app, @PATHEditFieldValueChanged, true);
            app.PATHEditField.Tag = 'pathtext';
            app.PATHEditField.Value = 'X:\';
            app.ListBox = uilistbox(app.RIXSplotUIFigure);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.DispersionEditFieldLabel = uilabel(app.RIXSplotUIFigure);
            app.DispersionEditFieldLabel.Text = 'Dispersion';
            app.DispersionEditField = uieditfield(app.RIXSplotUIFigure, 'text');
            app.DispersionEditField.Value = '1';
            app.PlotButton = uibutton(app.RIXSplotUIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ShiftEditField = uieditfield(app.RIXSplotUIFigure, 'text');
            app.ShiftEditField.Value = '0';
            app.ShiftButton = uibutton(app.RIXSplotUIFigure, 'push');
            app.ShiftButton.ButtonPushedFcn = createCallbackFcn(app, @ShiftButtonPushed, true);
            app.ShiftButton.Text = 'Shift';
            app.SaveButton = uibutton(app.RIXSplotUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CompareCheckBox = uicheckbox(app.RIXSplotUIFigure);
            app.CompareCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompareCheckBoxValueChanged, true);
            app.CompareCheckBox.Text = 'Compare';
            app.INFOTextAreaLabel = uilabel(app.RIXSplotUIFigure);
            app.INFOTextAreaLabel.Text = 'INFO';
            app.INFOTextArea = uitextarea(app.RIXSplotUIFigure);
            app.PositionEditField = uieditfield(app.RIXSplotUIFigure, 'text');
            app.PositionEditField.Editable = 'off';
            app.UIAxes = uiaxes(app.RIXSplotUIFigure);
            xlabel(app.UIAxes, 'Energy Transfer (eV)')
            ylabel(app.UIAxes, 'Intensity (arb. units)')
            % Show the figure after all components are created
            app.RIXSplotUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ADRESS_RIXS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RIXSplotUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RIXSplotUIFigure)
        end
    end
end